classdef Simon_app < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                  matlab.ui.Figure
        ClickthephotobelowtochooseanewimagefromyourcomputerLabel  matlab.ui.control.Label
        ClicktoselectimageButton  matlab.ui.control.Button
        developedbySimonChongKaiYuenCliveLaiYiChengNgShuYuLabel  matlab.ui.control.Label
        Label                     matlab.ui.control.Label
        Image                     matlab.ui.control.Image
    end

    
    methods (Access = private)
        
        function updateimage(~,PickedImage)
            A=imread(PickedImage); %imread() is reads image from local folder
            avg = ones(60) /30^2; %creats a  60x60 matrix with value 0.0011 to blur the image
            A_adj = imdivide(A,15); %imdivide() is creates a darker pixel
            counter = 0;
            c_bw = 0;
            prompt = 'How many area you want to select? ';
            input = inputdlg(prompt, 'Enter a value' , 1, {num2str(3)});
            while counter < str2double(input)
                bw = im2uint8(roipoly(A)); %im2uint8() is converts the grayscale, RGB, or binary image I to uint8, rescaling or offsetting the data as necessary.
                %roipoly() is creates an interactive polygon tool associated with the image displayed in the current figure.
                counter = counter + 1 ;
                c_bw = bitor(c_bw, bw);
            end
            bw_cmp = bitcmp(c_bw); %returns the bit-wise complement of bw
            roi = bitor(A_adj, bw_cmp); %returns the bit-wise OR of _adj & bw_cmp, cutted image
            not_roi = bitor(A, c_bw); %remaining of cut image
            filtered = imfilter(roi, avg, 'conv'); %imfilter() uses convolution to blur the selected area
            new_img = bitand(not_roi, filtered); %display the image by layers into a single image
            imshow(new_img); %display the result
            promptMessage = sprintf('Sucessfully! Do you want to save?');
            button = questdlg(promptMessage, 'Result', 'No', 'Yes', 'Yes');
            if strcmpi(button, 'Yes')
               	imsave();
            end
        end
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: ClicktoselectimageButton
        function ClicktoselectimageButtonPushed(app, event)
            % Display uigetfile dialog
            filterspec = {'*.jpg;*.tif;*.png;*.gif;*.jpeg','All Image Files'};
            [f, p] = uigetfile(filterspec);
            
            % Make sure user didn't cancel uigetfile dialog
            if (ischar(p))
                fname = [p f];
                updateimage(app, fname);
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.ClickthephotobelowtochooseanewimagefromyourcomputerLabel = uilabel(app.UIFigure);
            app.ClickthephotobelowtochooseanewimagefromyourcomputerLabel.Text = 'Click the photo to select a new image from your computer.';
            app.ClicktoselectimageButton = uibutton(app.UIFigure, 'push');
            app.ClicktoselectimageButton.ButtonPushedFcn = createCallbackFcn(app, @ClicktoselectimageButtonPushed, true);
            app.ClicktoselectimageButton.Icon = 'IMG_20200814_200954-01-01.jpeg';
            app.ClicktoselectimageButton.Text = {'Click to '; 'select image'};
            app.developedbySimonChongKaiYuenCliveLaiYiChengNgShuYuLabel = uilabel(app.UIFigure);
            app.developedbySimonChongKaiYuenCliveLaiYiChengNgShuYuLabel.Text = {'developed by:'; ''; 'Simon Chong Kai Yuen'; 'Clive Lai Yi Cheng'; 'Ng Shu Yu'};
            app.Label = uilabel(app.UIFigure);
            app.Label.Text = {'Welcome to our app, you can select any image to '; 'dark and blur it by drwing a freedom shape'};
            app.Image = uiimage(app.UIFigure);
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Simon_app

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end